classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ControlTab                      matlab.ui.container.Tab
        OperationModeButtonGroup        matlab.ui.container.ButtonGroup
        ManualButton                    matlab.ui.control.RadioButton
        AutomaticButton                 matlab.ui.control.RadioButton
        PDControllerGainsPanel          matlab.ui.container.Panel
        RollControlLabel                matlab.ui.control.Label
        PitchControlLabel               matlab.ui.control.Label
        ProportionalGainEditFieldLabel  matlab.ui.control.Label
        ProportionalGainEditFieldRoll   matlab.ui.control.NumericEditField
        TimeConstantEditFieldLabel      matlab.ui.control.Label
        TimeConstantEditFieldRoll       matlab.ui.control.NumericEditField
        DerivativeGainEditFieldLabel    matlab.ui.control.Label
        DerivativeGainEditFieldRoll     matlab.ui.control.NumericEditField
        AltitudeControlLabel            matlab.ui.control.Label
        TimeConstantEditField_2Label    matlab.ui.control.Label
        TimeConstantEditFieldPitch      matlab.ui.control.NumericEditField
        DerivativeGainEditField_2Label  matlab.ui.control.Label
        DerivativeGainEditFieldPitch    matlab.ui.control.NumericEditField
        ProportionalGainEditField_2Label  matlab.ui.control.Label
        ProportionalGainEditFieldPitch  matlab.ui.control.NumericEditField
        TimeConstantEditField_3Label    matlab.ui.control.Label
        TimeConstantEditFieldAltitude   matlab.ui.control.NumericEditField
        DerivativeGainEditField_3Label  matlab.ui.control.Label
        DerivativeGainEditFieldAltitude  matlab.ui.control.NumericEditField
        ProportionalGainEditField_3Label  matlab.ui.control.Label
        ProportionalGainEditFieldAltitude  matlab.ui.control.NumericEditField
        MotorControlPanel               matlab.ui.container.Panel
        AllSliderLabel                  matlab.ui.control.Label
        AllSlider                       matlab.ui.control.Slider
        Motor3SliderLabel               matlab.ui.control.Label
        Motor3Slider                    matlab.ui.control.Slider
        Motor1SliderLabel               matlab.ui.control.Label
        Motor1Slider                    matlab.ui.control.Slider
        Motor2SliderLabel               matlab.ui.control.Label
        Motor2Slider                    matlab.ui.control.Slider
        MotorConfigurationPanel         matlab.ui.container.Panel
        MaxMotorVoltageEditFieldLabel   matlab.ui.control.Label
        MaxMotorVoltageEditField        matlab.ui.control.NumericEditField
        InitialCenterofMassPositionPanel  matlab.ui.container.Panel
        CoMPositionEditFieldLabel       matlab.ui.control.Label
        CoMPositionEditFieldX           matlab.ui.control.NumericEditField
        CoMPositionEditFieldY           matlab.ui.control.NumericEditField
        CoMPositionEditFieldZ           matlab.ui.control.NumericEditField
        CameraControlTab                matlab.ui.container.Tab
        CameraSelectionButtonGroup      matlab.ui.container.ButtonGroup
        BottomCameraAButton             matlab.ui.control.RadioButton
        BottomCameraBButton             matlab.ui.control.RadioButton
        SideCameraPanel                 matlab.ui.container.Panel
        ROIHoldPositionpxEditFieldLabel  matlab.ui.control.Label
        ROIHoldPositionpxEditFieldSideRow  matlab.ui.control.NumericEditField
        ROIHoldPositionpxEditFieldSideCol  matlab.ui.control.NumericEditField
        HoldReleaseButtonSide           matlab.ui.control.Button
        ROISizeEditFieldLabel           matlab.ui.control.Label
        ROISizeEditFieldSideRow         matlab.ui.control.NumericEditField
        ROISizeEditFieldSideCol         matlab.ui.control.NumericEditField
        ImageDimensionsEditFieldLabel   matlab.ui.control.Label
        ImageDimensionsEditFieldSideRow  matlab.ui.control.NumericEditField
        ImageDimensionsEditFieldSideCol  matlab.ui.control.NumericEditField
        CrosshairWidthEditFieldLabel    matlab.ui.control.Label
        CrosshairWidthEditFieldSide     matlab.ui.control.NumericEditField
        VideoDecimationEditFieldLabel   matlab.ui.control.Label
        VideoDecimationEditFieldSide    matlab.ui.control.NumericEditField
        ImageWidthEditFieldLabel        matlab.ui.control.Label
        ImageWidthEditFieldSide         matlab.ui.control.NumericEditField
        BottomCameraPanel               matlab.ui.container.Panel
        ROIHoldPositionpxEditFieldLabel_2  matlab.ui.control.Label
        ROIHoldPositionpxEditFieldBottomARow  matlab.ui.control.NumericEditField
        ROIHoldPositionpxEditFieldBottomACol  matlab.ui.control.NumericEditField
        HoldReleaseButtonBottomA        matlab.ui.control.Button
        ROISizeEditField_5Label         matlab.ui.control.Label
        ROISizeEditFieldBottomARow      matlab.ui.control.NumericEditField
        ROISizeEditFieldBottomACol      matlab.ui.control.NumericEditField
        ImageDimensionsEditField_2Label  matlab.ui.control.Label
        ImageDimensionsEditFieldBottomRow  matlab.ui.control.NumericEditField
        ImageDimensionsEditFieldBottomCol  matlab.ui.control.NumericEditField
        CrosshairWidthEditField_2Label  matlab.ui.control.Label
        CrosshairWidthEditFieldBottomA  matlab.ui.control.NumericEditField
        VideoDecimationEditField_2Label  matlab.ui.control.Label
        VideoDecimationEditFieldBottom  matlab.ui.control.NumericEditField
        ImageWidthEditField_2Label      matlab.ui.control.Label
        ImageWidthEditFieldBottom       matlab.ui.control.NumericEditField
        BottomCameraALabel              matlab.ui.control.Label
        BottomCameraBLabel              matlab.ui.control.Label
        ROIHoldPositionpxEditFieldBottomBCol  matlab.ui.control.NumericEditField
        HoldReleaseButtonBottomB        matlab.ui.control.Button
        ROISizeEditFieldBottomBCol      matlab.ui.control.NumericEditField
        ROIHoldPositionpxEditFieldLabel_4  matlab.ui.control.Label
        ROIHoldPositionpxEditFieldBottomBRow  matlab.ui.control.NumericEditField
        ROISizeEditField_12Label        matlab.ui.control.Label
        ROISizeEditFieldBottomBRow      matlab.ui.control.NumericEditField
        CrosshairWidthEditField_4Label  matlab.ui.control.Label
        CrosshairWidthEditFieldBottomB  matlab.ui.control.NumericEditField
        BothCamerasLabel                matlab.ui.control.Label
        SlantCameraPanel                matlab.ui.container.Panel
        ROIHoldPositionpxEditFieldLabel_3  matlab.ui.control.Label
        ROIHoldPositionpxEditFieldSlantRow  matlab.ui.control.NumericEditField
        ROIHoldPositionpxEditFieldSlantCol  matlab.ui.control.NumericEditField
        HoldReleaseButtonSideSlant      matlab.ui.control.Button
        ROISizeEditField_8Label         matlab.ui.control.Label
        ROISizeEditFieldSlantRow        matlab.ui.control.NumericEditField
        ROISizeEditFieldSlantCol        matlab.ui.control.NumericEditField
        ImageDimensionsEditField_3Label  matlab.ui.control.Label
        ImageDimensionsEditFieldSlantRow  matlab.ui.control.NumericEditField
        ImageDimensionsEditFieldSlantCol  matlab.ui.control.NumericEditField
        CrosshairWidthEditField_3Label  matlab.ui.control.Label
        CrosshairWidthEditFieldSlant    matlab.ui.control.NumericEditField
        VideoDecimationEditField_3Label  matlab.ui.control.Label
        VideoDecimationEditFieldSlant   matlab.ui.control.NumericEditField
        ImageWidthEditField_3Label      matlab.ui.control.Label
        ImageWidthEditFieldSlant        matlab.ui.control.NumericEditField
        CameraSettingsTab               matlab.ui.container.Tab
        DotPropertiesPanel              matlab.ui.container.Panel
        SideDotSeparationDistanceEditField_2Label  matlab.ui.control.Label
        SideDotSeparationDistanceEditField  matlab.ui.control.NumericEditField
        SideDotPositionVectorEditFieldLabel_2  matlab.ui.control.Label
        SideDotPositionVectorEditFieldX  matlab.ui.control.NumericEditField
        BottomADotSeparationDistanceEditField_2Label  matlab.ui.control.Label
        BottomADotSeparationDistanceEditField  matlab.ui.control.NumericEditField
        BottomADotPositionVectorEditFieldLabel_2  matlab.ui.control.Label
        BottomADotPositionVectorEditFieldX  matlab.ui.control.NumericEditField
        BottomBDotSeparationDistanceEditField_2Label  matlab.ui.control.Label
        BottomBDotSeparationDistanceEditField  matlab.ui.control.NumericEditField
        BottomBDotPositionValuieEditFieldLabel_2  matlab.ui.control.Label
        BottomBDotPositionValuieEditFieldX  matlab.ui.control.NumericEditField
        SideDotPositionVectorEditFieldY  matlab.ui.control.NumericEditField
        SideDotPositionVectorEditFieldZ  matlab.ui.control.NumericEditField
        BottomADotPositionVectorEditFieldY  matlab.ui.control.NumericEditField
        BottomADotPositionVectorEditFieldZ  matlab.ui.control.NumericEditField
        BottomBDotPositionValuieEditFieldY  matlab.ui.control.NumericEditField
        BottomBDotPositionValuieEditFieldZ  matlab.ui.control.NumericEditField
        CameraSpecificationsPanel       matlab.ui.container.Panel
        CameraFOVEditFieldLabel_2       matlab.ui.control.Label
        CameraFOVEditFieldHor           matlab.ui.control.NumericEditField
        CameraFOVEditFieldVer           matlab.ui.control.NumericEditField
        ImageErrorSettingsPanel         matlab.ui.container.Panel
        ThresholdEditField_2Label       matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        DebounceTimeEditField_2Label    matlab.ui.control.Label
        DebounceTimeEditField           matlab.ui.control.NumericEditField
        CameraOrientationPanel          matlab.ui.container.Panel
        SideCameraLabel2                matlab.ui.control.Label
        EulerAngleEditFieldLabel_2      matlab.ui.control.Label
        EulerAngleEditFieldSideRoll     matlab.ui.control.NumericEditField
        BottomCameraLabel               matlab.ui.control.Label
        EulerAngleEditField_2Label_2    matlab.ui.control.Label
        EulerAngleEditFieldBottomRoll   matlab.ui.control.NumericEditField
        EulerAngleEditFieldBottomPitch  matlab.ui.control.NumericEditField
        EulerAngleEditFieldBottomYaw    matlab.ui.control.NumericEditField
        PositionEditField_8Label_2      matlab.ui.control.Label
        PositionEditFieldBottomX        matlab.ui.control.NumericEditField
        PositionEditFieldBottomY        matlab.ui.control.NumericEditField
        PositionEditFieldBottomZ        matlab.ui.control.NumericEditField
        SlantCameraLabel2               matlab.ui.control.Label
        EulerAngleEditField_3Label_2    matlab.ui.control.Label
        EulerAngleEditFieldSlantRoll    matlab.ui.control.NumericEditField
        PositionEditField_11Label_2     matlab.ui.control.Label
        PositionEditFieldSlantX         matlab.ui.control.NumericEditField
        PositionEditFieldSlantY         matlab.ui.control.NumericEditField
        EulerAngleEditFieldSlantPitch   matlab.ui.control.NumericEditField
        EulerAngleEditFieldSlantYaw     matlab.ui.control.NumericEditField
        PositionEditFieldSlantZ         matlab.ui.control.NumericEditField
        PositionEditField_16Label_2     matlab.ui.control.Label
        PositionEditFieldSideX          matlab.ui.control.NumericEditField
        EulerAngleEditFieldSidePitch    matlab.ui.control.NumericEditField
        EulerAngleEditFieldSideYaw      matlab.ui.control.NumericEditField
        PositionEditFieldSideY          matlab.ui.control.NumericEditField
        PositionEditFieldSideZ          matlab.ui.control.NumericEditField
        CommunicationTab                matlab.ui.container.Tab
        UDPPTPConfigurationPanel        matlab.ui.container.Panel
        PTPOffsetEditFieldLabel         matlab.ui.control.Label
        PTPOffsetEditField              matlab.ui.control.NumericEditField
        SyncIntervalEditFieldLabel      matlab.ui.control.Label
        SyncIntervalEditField           matlab.ui.control.NumericEditField
        Machine3Panel                   matlab.ui.container.Panel
        PortNumberEditFieldLabel        matlab.ui.control.Label
        PortNumberEditFieldMachine3     matlab.ui.control.NumericEditField
        IPAddressEditFieldLabel         matlab.ui.control.Label
        IPAddressEditFieldMachine3      matlab.ui.control.EditField
        MinDelayEditFieldLabel          matlab.ui.control.Label
        MinDelayEditFieldMachine3       matlab.ui.control.NumericEditField
        Machine4Panel                   matlab.ui.container.Panel
        IPAddressEditField_2Label       matlab.ui.control.Label
        IPAddressEditFieldMachine4      matlab.ui.control.EditField
        PortNumberEditField_2Label      matlab.ui.control.Label
        PortNumberEditFieldMachine4     matlab.ui.control.NumericEditField
        MinDelayEditField_2Label        matlab.ui.control.Label
        MinDelayEditFieldMachine4       matlab.ui.control.NumericEditField
        Machine5Panel                   matlab.ui.container.Panel
        IPAddressEditField_3Label       matlab.ui.control.Label
        IPAddressEditFieldMachine5      matlab.ui.control.EditField
        PortNumberEditField_3Label      matlab.ui.control.Label
        PortNumberEditFieldMachine5     matlab.ui.control.NumericEditField
        MinDelayEditField_3Label        matlab.ui.control.Label
        MinDelayEditFieldMachine5       matlab.ui.control.NumericEditField
        Machine2Panel                   matlab.ui.container.Panel
        AnnounceIntervalEditFieldLabel  matlab.ui.control.Label
        AnnounceIntervalEditField       matlab.ui.control.NumericEditField
    end


    methods (Access = private)

        % Value changed function: ProportionalGainEditFieldRoll
        function ProportionalGainEditFieldRollValueChanged(app, event)
            value = app.ProportionalGainEditFieldRoll.Value;
            global params;
            params.rollCtrlkp_Prad.Value = value;
            %set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: DerivativeGainEditFieldRoll
        function DerivativeGainEditFieldRollValueChanged(app, event)
            value = app.DerivativeGainEditFieldRoll.Value;
            global params;
            params.rollCtrlkd_sPrad.Value = value;
            %set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: TimeConstantEditFieldRoll
        function TimeConstantEditFieldRollValueChanged(app, event)
            value = app.TimeConstantEditFieldRoll.Value;
            global params;
            params.rollCtrltau_s.Value = value;
            %set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: ProportionalGainEditFieldPitch
        function ProportionalGainEditFieldPitchValueChanged(app, event)
            value = app.ProportionalGainEditFieldPitch.Value;
            global params;
            params.pitchCtrlkp_Prad.Value = value;
            %set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: DerivativeGainEditFieldPitch
        function DerivativeGainEditFieldPitchValueChanged(app, event)
            value = app.DerivativeGainEditFieldPitch.Value;
            global params;
            params.pitchCtrlkd_sPrad.Value = value;
            %set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: TimeConstantEditFieldPitch
        function TimeConstantEditFieldPitchValueChanged(app, event)
            value = app.TimeConstantEditFieldPitch.Value;
            global params;
            params.pitchCtrltau_s.Value = value;
            %set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: ProportionalGainEditFieldAltitude
        function ProportionalGainEditFieldAltitudeValueChanged(app, event)
            value = app.ProportionalGainEditFieldAltitude.Value;
            global params;
            params.altCtrlkp_Prad.Value = value;
            %set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: DerivativeGainEditFieldAltitude
        function DerivativeGainEditFieldAltitudeValueChanged(app, event)
            value = app.DerivativeGainEditFieldAltitude.Value;
            global params;
            params.altCtrlkd_sPrad.Value = value;
            %set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: TimeConstantEditFieldAltitude
        function TimeConstantEditFieldAltitudeValueChanged(app, event)
            value = app.TimeConstantEditFieldAltitude.Value;
            global params;
            params.altCtrltau_s.Value = value;
            %set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: Motor1Slider
        function Motor1SliderValueChanged(app, event)
            value = app.Motor1Slider.Value;
            global params;
            params.mtr1ManulaSpdCmd_na.Value = value;
            %set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: Motor2Slider
        function Motor2SliderValueChanged(app, event)
            value = app.Motor2Slider.Value;
            global params;
            params.mtr2ManulaSpdCmd_na.Value = value;
            %set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: Motor3Slider
        function Motor3SliderValueChanged(app, event)
            value = app.Motor3Slider.Value;
            global params;
            params.mtr3ManulaSpdCmd_na.Value = value;
            %set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: MaxMotorVoltageEditField
        function MaxMotorVoltageEditFieldValueChanged(app, event)
            value = app.MaxMotorVoltageEditField.Value;
            global params;
            params.maxOutVltg_vlt.Value = value;
            %set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: CoMPositionEditFieldX
        function CoMPositionEditFieldXValueChanged(app, event)
            value = app.CoMPositionEditFieldX.Value;
            global params;
            params.initCoMPosVec_cm.Value(1) = value;
            %set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: AllSlider
        function AllSliderValueChanged(app, event)
            value = app.AllSlider.Value;
            global params;
            params.mtr1ManulaSpdCmd_na.Value = value;
            params.mtr2ManulaSpdCmd_na.Value = value;
            params.mtr3ManulaSpdCmd_na.Value = value;
            %set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: CoMPositionEditFieldY
        function CoMPositionEditFieldYValueChanged(app, event)
            value = app.CoMPositionEditFieldY.Value;
            global params;
            params.initCoMPosVec_cm.Value(2) = value;
            %set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: CoMPositionEditFieldZ
        function CoMPositionEditFieldZValueChanged(app, event)
            value = app.CoMPositionEditFieldZ.Value;
            global params;
            params.initCoMPosVec_cm.Value(3) = value;
            %set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: CameraFOVEditFieldHor
        function CameraFOVEditFieldHorValueChanged(app, event)
            value = app.CameraFOVEditFieldHor.Value;
            global params;
            params.cameraFOV_deg.Value(1) = value;
        end

        % Value changed function: CameraFOVEditFieldVer
        function CameraFOVEditFieldVerValueChanged(app, event)
            value = app.CameraFOVEditFieldVer.Value;
            global params;
            params.cameraFOV_deg.Value(2) = value;
        end

        % Value changed function: PositionEditFieldSideX
        function PositionEditFieldSideXValueChanged(app, event)
            value = app.PositionEditFieldSideX.Value;
            global params;
            params.sideCamPosVec_cm.Value(1) = value;
        end

        % Value changed function: PositionEditFieldSideY
        function PositionEditFieldSideYValueChanged(app, event)
            value = app.PositionEditFieldSideY.Value;
            global params;
            params.sideCamPosVec_cm.Value(2) = value;
        end

        % Value changed function: PositionEditFieldSideZ
        function PositionEditFieldSideZValueChanged(app, event)
            value = app.PositionEditFieldSideZ.Value;
            global params;
            params.sideCamPosVec_cm.Value(3) = value;
        end

        % Value changed function: EulerAngleEditFieldSideRoll
        function EulerAngleEditFieldSideRollValueChanged(app, event)
            value = app.EulerAngleEditFieldSideRoll.Value;
            global params;
            params.sideCamEulAng_deg.Value(1) = value;
        end

        % Value changed function: EulerAngleEditFieldSidePitch
        function EulerAngleEditFieldSidePitchValueChanged(app, event)
            value = app.EulerAngleEditFieldSidePitch.Value;
            global params;
            params.sideCamEulAng_deg.Value(2) = value;
        end

        % Value changed function: EulerAngleEditFieldSideYaw
        function EulerAngleEditFieldSideYawValueChanged(app, event)
            value = app.EulerAngleEditFieldSideYaw.Value;
            global params;
            params.sideCamEulAng_deg.Value(3) = value;
        end

        % Value changed function: PositionEditFieldBottomX
        function PositionEditFieldBottomXValueChanged(app, event)
            value = app.PositionEditFieldBottomX.Value;
            global params;
            params.botCamPosVec_cm.Value(1) = value;
        end

        % Value changed function: PositionEditFieldBottomY
        function PositionEditFieldBottomYValueChanged(app, event)
            value = app.PositionEditFieldBottomY.Value;
            global params;
            params.botCamPosVec_cm.Value(2) = value;
        end

        % Value changed function: PositionEditFieldBottomZ
        function PositionEditFieldBottomZValueChanged(app, event)
            value = app.PositionEditFieldBottomZ.Value;
            global params;
            params.botCamPosVec_cm.Value(3) = value;
        end

        % Value changed function: EulerAngleEditFieldBottomRoll
        function EulerAngleEditFieldBottomRollValueChanged(app, event)
            value = app.EulerAngleEditFieldBottomRoll.Value;
            global params;
            params.botCamEulAng_deg.Value(1) = value;
        end

        % Value changed function: EulerAngleEditFieldBottomPitch
        function EulerAngleEditFieldBottomPitchValueChanged(app, event)
            value = app.EulerAngleEditFieldBottomPitch.Value;
            global params;
            params.botCamEulAng_deg.Value(2) = value;
        end

        % Value changed function: EulerAngleEditFieldBottomYaw
        function EulerAngleEditFieldBottomYawValueChanged(app, event)
            value = app.EulerAngleEditFieldBottomYaw.Value;
            global params;
            params.botCamEulAng_deg.Value(3) = value;
        end

        % Value changed function: PositionEditFieldSlantX
        function PositionEditFieldSlantXValueChanged(app, event)
            value = app.PositionEditFieldSlantX.Value;
            global params;
            params.slntCamPosVec_cm.Value(1) = value;
        end

        % Value changed function: PositionEditFieldSlantY
        function PositionEditFieldSlantYValueChanged(app, event)
            value = app.PositionEditFieldSlantY.Value;
            global params;
            params.slntCamPosVec_cm.Value(2) = value;
        end

        % Value changed function: PositionEditFieldSlantZ
        function PositionEditFieldSlantZValueChanged(app, event)
            value = app.PositionEditFieldSlantZ.Value;
            global params;
            params.slntCamPosVec_cm.Value(3) = value;
        end

        % Value changed function: EulerAngleEditFieldSlantRoll
        function EulerAngleEditFieldSlantRollValueChanged(app, event)
            value = app.EulerAngleEditFieldSlantRoll.Value;
            global params;
            params.slntCamEulAng_deg.Value(1) = value;
        end

        % Value changed function: EulerAngleEditFieldSlantPitch
        function EulerAngleEditFieldSlantPitchValueChanged(app, event)
            value = app.EulerAngleEditFieldSlantPitch.Value;
            global params;
            params.slntCamEulAng_deg.Value(2) = value;
        end

        % Value changed function: EulerAngleEditFieldSlantYaw
        function EulerAngleEditFieldSlantYawValueChanged(app, event)
            value = app.EulerAngleEditFieldSlantYaw.Value;
            global params;
            params.slntCamEulAng_deg.Value(3) = value;
        end

        % Value changed function: SideDotSeparationDistanceEditField
        function SideDotSeparationDistanceEditFieldValueChanged(app, event)
            value = app.SideDotSeparationDistanceEditField.Value;
            global params;
            params.sideDotSepDist_cm.Value = value;
        end

        % Value changed function: SideDotPositionVectorEditFieldX
        function SideDotPositionVectorEditFieldXValueChanged(app, event)
            value = app.SideDotPositionVectorEditFieldX.Value;
            global params;
            params.sideDotPosVec_cm.Value(1) = value;
        end

        % Value changed function: SideDotPositionVectorEditFieldY
        function SideDotPositionVectorEditFieldYValueChanged(app, event)
            value = app.SideDotPositionVectorEditFieldY.Value;
            global params;
            params.sideDotPosVec_cm.Value(2) = value;
        end

        % Value changed function: SideDotPositionVectorEditFieldZ
        function SideDotPositionVectorEditFieldZValueChanged(app, event)
            value = app.SideDotPositionVectorEditFieldZ.Value;
            global params;
            params.sideDotPosVec_cm.Value(3) = value;
        end

        % Value changed function: 
        % BottomADotSeparationDistanceEditField
        function BottomADotSeparationDistanceEditFieldValueChanged(app, event)
            value = app.BottomADotSeparationDistanceEditField.Value;
            global params;
            params.botADotSepDist_cm.Value = value;
        end

        % Value changed function: BottomADotPositionVectorEditFieldX
        function BottomADotPositionVectorEditFieldXValueChanged(app, event)
            value = app.BottomADotPositionVectorEditFieldX.Value;
            global params;
            params.botADotPosVec_cm.Value(1) = value;
        end

        % Value changed function: BottomADotPositionVectorEditFieldY
        function BottomADotPositionVectorEditFieldYValueChanged(app, event)
            value = app.BottomADotPositionVectorEditFieldY.Value;
            global params;
            params.botADotPosVec_cm.Value(2) = value;
        end

        % Value changed function: BottomADotPositionVectorEditFieldZ
        function BottomADotPositionVectorEditFieldZValueChanged(app, event)
            value = app.BottomADotPositionVectorEditFieldZ.Value;
            global params;
            params.botADotPosVec_cm.Value(3) = value;
        end

        % Value changed function: 
        % BottomBDotSeparationDistanceEditField
        function BottomBDotSeparationDistanceEditFieldValueChanged(app, event)
            value = app.BottomBDotSeparationDistanceEditField.Value;
            global params;
            params.botBDotSepDist_cm.Value = value;
        end

        % Value changed function: BottomBDotPositionValuieEditFieldX
        function BottomBDotPositionValuieEditFieldXValueChanged(app, event)
            value = app.BottomBDotPositionValuieEditFieldX.Value;
            global params;
            params.botBDotPosVec_cm.Value(1) = value;
        end

        % Value changed function: BottomBDotPositionValuieEditFieldY
        function BottomBDotPositionValuieEditFieldYValueChanged(app, event)
            value = app.BottomBDotPositionValuieEditFieldY.Value;
            global params;
            params.botBDotPosVec_cm.Value(2) = value;
        end

        % Value changed function: BottomBDotPositionValuieEditFieldZ
        function BottomBDotPositionValuieEditFieldZValueChanged(app, event)
            value = app.BottomBDotPositionValuieEditFieldZ.Value;
            global params;
            params.botBDotPosVec_cm.Value(3) = value;
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;
            global params;
            params.imagProcErrThrsh_na.Value = value;
        end

        % Value changed function: DebounceTimeEditField
        function DebounceTimeEditFieldValueChanged(app, event)
            value = app.DebounceTimeEditField.Value;
            global params;
            params.imagProcErrDbncTm_s.Value = value;
        end

        % Value changed function: ROIHoldPositionpxEditFieldSideRow
        function ROIHoldPositionpxEditFieldSideRowValueChanged(app, event)
            value = app.ROIHoldPositionpxEditFieldSideRow.Value;
            global params;
            params.roiSideHldPos_px.Value(1) = value;
        end

        % Value changed function: ROIHoldPositionpxEditFieldSideCol
        function ROIHoldPositionpxEditFieldSideColValueChanged(app, event)
            value = app.ROIHoldPositionpxEditFieldSideCol.Value;
            global params;
            params.roiSideHldPos_px.Value(2) = value;
        end

        % Value changed function: ROISizeEditFieldSideRow
        function ROISizeEditFieldSideRowValueChanged(app, event)
            value = app.ROISizeEditFieldSideRow.Value;
            global params;
            params.roiSideSize_px.Value(1) = value;
        end

        % Value changed function: ROISizeEditFieldSideCol
        function ROISizeEditFieldSideColValueChanged(app, event)
            value = app.ROISizeEditFieldSideCol.Value;
            global params;
            params.roiSideSize_px.Value(2) = value;
        end

        % Value changed function: ImageDimensionsEditFieldSideRow
        function ImageDimensionsEditFieldSideRowValueChanged(app, event)
            value = app.ImageDimensionsEditFieldSideRow.Value;
            global params;
            params.sideImageSize_px.Value(1) = value;
        end

        % Value changed function: ImageDimensionsEditFieldSideCol
        function ImageDimensionsEditFieldSideColValueChanged(app, event)
            value = app.ImageDimensionsEditFieldSideCol.Value;
            global params;
            params.sideImageSize_px.Value(2) = value;
        end

        % Value changed function: CrosshairWidthEditFieldSide
        function CrosshairWidthEditFieldSideValueChanged(app, event)
            value = app.CrosshairWidthEditFieldSide.Value;
            global params;
            params.sideCrsHrWdth_px.Value = value;
        end

        % Value changed function: VideoDecimationEditFieldSide
        function VideoDecimationEditFieldSideValueChanged(app, event)
            value = app.VideoDecimationEditFieldSide.Value;
            global params;
            params.sideVidStrmDec_na.Value = value;
        end

        % Value changed function: ImageWidthEditFieldSide
        function ImageWidthEditFieldSideValueChanged(app, event)
            value = app.ImageWidthEditFieldSide.Value;
            global params;
            params.sideVidStrmImgWdth_px.Value = value;
        end

        % Value changed function: ROIHoldPositionpxEditFieldBottomARow
        function ROIHoldPositionpxEditFieldBottomARowValueChanged(app, event)
            value = app.ROIHoldPositionpxEditFieldBottomARow.Value;
            global params;
            params.roiBotAHldPos_px.Value(1) = value;
        end

        % Value changed function: ROIHoldPositionpxEditFieldBottomACol
        function ROIHoldPositionpxEditFieldBottomAColValueChanged(app, event)
            value = app.ROIHoldPositionpxEditFieldBottomACol.Value;
            global params;
            params.roiBotAHldPos_px.Value(2) = value;
        end

        % Value changed function: ROIHoldPositionpxEditFieldBottomBRow
        function ROIHoldPositionpxEditFieldBottomBRowValueChanged(app, event)
            value = app.ROIHoldPositionpxEditFieldBottomBRow.Value;
            global params;
            params.roiBotBHldPos_px.Value(1) = value;
        end

        % Value changed function: ROIHoldPositionpxEditFieldBottomBCol
        function ROIHoldPositionpxEditFieldBottomBColValueChanged(app, event)
            value = app.ROIHoldPositionpxEditFieldBottomBCol.Value;
            global params;
            params.roiBotBHldPos_px.Value(2) = value;
        end

        % Value changed function: ROISizeEditFieldBottomARow
        function ROISizeEditFieldBottomARowValueChanged(app, event)
            value = app.ROISizeEditFieldBottomARow.Value;
            global params;
            params.roiBotASize_px.Value(1) = value;
        end

        % Value changed function: ROISizeEditFieldBottomACol
        function ROISizeEditFieldBottomAColValueChanged(app, event)
            value = app.ROISizeEditFieldBottomACol.Value;
            global params;
            params.roiBotASize_px.Value(2) = value;
        end

        % Value changed function: ROISizeEditFieldBottomBRow
        function ROISizeEditFieldBottomBRowValueChanged(app, event)
            value = app.ROISizeEditFieldBottomBRow.Value;
            global params;
            params.roiBotBSize_px.Value(1) = value;
        end

        % Value changed function: ROISizeEditFieldBottomBCol
        function ROISizeEditFieldBottomBColValueChanged(app, event)
            value = app.ROISizeEditFieldBottomBCol.Value;
            global params;
            params.roiBotBSize_px.Value(2) = value;
        end

        % Value changed function: ImageDimensionsEditFieldBottomRow
        function ImageDimensionsEditFieldBottomRowValueChanged(app, event)
            value = app.ImageDimensionsEditFieldBottomRow.Value;
            global params;
            params.botImageSize_px.Value(1) = value;
        end

        % Value changed function: ImageDimensionsEditFieldBottomCol
        function ImageDimensionsEditFieldBottomColValueChanged(app, event)
            value = app.ImageDimensionsEditFieldBottomCol.Value;
            global params;
            params.botImageSize_px.Value(2) = value;
        end

        % Value changed function: CrosshairWidthEditFieldBottomA
        function CrosshairWidthEditFieldBottomAValueChanged(app, event)
            value = app.CrosshairWidthEditFieldBottomA.Value;
            global params;
            params.botACrsHrWdth_px.Value = value;
        end

        % Value changed function: CrosshairWidthEditFieldBottomB
        function CrosshairWidthEditFieldBottomBValueChanged(app, event)
            value = app.CrosshairWidthEditFieldBottomB.Value;
            global params;
            params.botBCrsHrWdth_px.Value = value;
        end

        % Value changed function: VideoDecimationEditFieldBottom
        function VideoDecimationEditFieldBottomValueChanged(app, event)
            value = app.VideoDecimationEditFieldBottom.Value;
            global params;
            params.botVidStrmDec_na.Value = value;
        end

        % Value changed function: ImageWidthEditFieldBottom
        function ImageWidthEditFieldBottomValueChanged(app, event)
            value = app.ImageWidthEditFieldBottom.Value;
            global params;
            params.botVidStrmImgWdth_px.Value = value;
        end

        % Value changed function: ROIHoldPositionpxEditFieldSlantRow
        function ROIHoldPositionpxEditFieldSlantRowValueChanged(app, event)
            value = app.ROIHoldPositionpxEditFieldSlantRow.Value;
            global params;
            params.roiSlntHldPos_px.Value(1) = value;
        end

        % Value changed function: ROIHoldPositionpxEditFieldSlantCol
        function ROIHoldPositionpxEditFieldSlantColValueChanged(app, event)
            value = app.ROIHoldPositionpxEditFieldSlantCol.Value;
            global params;
            params.roiSlntHldPos_px.Value(2) = value;
        end

        % Value changed function: ROISizeEditFieldSlantRow
        function ROISizeEditFieldSlantRowValueChanged(app, event)
            value = app.ROISizeEditFieldSlantRow.Value;
            global params;
            params.roiSlntSize_px.Value(1) = value;
        end

        % Value changed function: ROISizeEditFieldSlantCol
        function ROISizeEditFieldSlantColValueChanged(app, event)
            value = app.ROISizeEditFieldSlantCol.Value;
            global params;
            params.roiSlntSize_px.Value(2) = value;
        end

        % Value changed function: ImageDimensionsEditFieldSlantRow
        function ImageDimensionsEditFieldSlantRowValueChanged(app, event)
            value = app.ImageDimensionsEditFieldSlantRow.Value;
            global params;
            params.slntImageSize_px.Value(1) = value;
        end

        % Value changed function: ImageDimensionsEditFieldSlantCol
        function ImageDimensionsEditFieldSlantColValueChanged(app, event)
            value = app.ImageDimensionsEditFieldSlantCol.Value;
            global params;
            params.slntImageSize_px.Value(2) = value;
        end

        % Value changed function: CrosshairWidthEditFieldSlant
        function CrosshairWidthEditFieldSlantValueChanged(app, event)
            value = app.CrosshairWidthEditFieldSlant.Value;
            global params;
            params.slntCrsHrWdth_px.Value = value;
        end

        % Value changed function: VideoDecimationEditFieldSlant
        function VideoDecimationEditFieldSlantValueChanged(app, event)
            value = app.VideoDecimationEditFieldSlant.Value;
            global params;
            params.slntVidStrmDec_na.Value = value;
        end

        % Value changed function: ImageWidthEditFieldSlant
        function ImageWidthEditFieldSlantValueChanged(app, event)
            value = app.ImageWidthEditFieldSlant.Value;
            global params;
            params.slntVidStrmImgWdth_px.Value = value;
        end

        % Value changed function: PTPOffsetEditField
        function PTPOffsetEditFieldValueChanged(app, event)
            value = app.PTPOffsetEditField.Value;
            global params;
            params.offsetThreshold_s.Value = value;
        end

        % Value changed function: SyncIntervalEditField
        function SyncIntervalEditFieldValueChanged(app, event)
            value = app.SyncIntervalEditField.Value;
            global params;
            params.syncInterval_s.Value = value;
        end

        % Value changed function: AnnounceIntervalEditField
        function AnnounceIntervalEditFieldValueChanged(app, event)
            value = app.AnnounceIntervalEditField.Value;
            global params;
            params.announceInterval_s.Value = value;
        end

        % Value changed function: PortNumberEditFieldMachine3
        function PortNumberEditFieldMachine3ValueChanged(app, event)
            value = app.PortNumberEditFieldMachine3.Value;
            global params;
            params.sideToPort_na.Value = value;
        end

        % Value changed function: MinDelayEditFieldMachine3
        function MinDelayEditFieldMachine3ValueChanged(app, event)
            value = app.MinDelayEditFieldMachine3.Value;
            global params;
            params.sideMinDelay_s.Value = value;
        end

        % Value changed function: PortNumberEditFieldMachine4
        function PortNumberEditFieldMachine4ValueChanged(app, event)
            value = app.PortNumberEditFieldMachine4.Value;
            global params;
            params.bottomToPort_na.Value = value;
        end

        % Value changed function: MinDelayEditFieldMachine4
        function MinDelayEditFieldMachine4ValueChanged(app, event)
            value = app.MinDelayEditFieldMachine4.Value;
            global params;
            params.bottomMinDelay_s.Value = value;
        end

        % Value changed function: PortNumberEditFieldMachine5
        function PortNumberEditFieldMachine5ValueChanged(app, event)
            value = app.PortNumberEditFieldMachine5.Value;
            global params;
            params.slantToPort_na.Value = value;
        end

        % Value changed function: MinDelayEditFieldMachine5
        function MinDelayEditFieldMachine5ValueChanged(app, event)
            value = app.MinDelayEditFieldMachine5.Value;
            global params;
            params.slantMinDelay_s.Value = value;
        end

        % Value changed function: IPAddressEditFieldMachine3
        function IPAddressEditFieldMachine3ValueChanged(app, event)
            value = app.IPAddressEditFieldMachine3.Value;
            str = str2num(strrep(value,'.',' '));
            global params;
            params.sideIPAddress_na.Value(1) = str(1);
            params.sideIPAddress_na.Value(2) = str(2);
            params.sideIPAddress_na.Value(3) = str(3);
            params.sideIPAddress_na.Value(4) = str(4);
        end

        % Value changed function: IPAddressEditFieldMachine4
        function IPAddressEditFieldMachine4ValueChanged(app, event)
            value = app.IPAddressEditFieldMachine4.Value;
            str = str2num(strrep(value,'.',' '));
            global params;
            params.bottomIPAddress_na.Value(1) = str(1);
            params.bottomIPAddress_na.Value(2) = str(2);
            params.bottomIPAddress_na.Value(3) = str(3);
            params.bottomIPAddress_na.Value(4) = str(4);
        end

        % Value changed function: IPAddressEditFieldMachine5
        function IPAddressEditFieldMachine5ValueChanged(app, event)
            value = app.IPAddressEditFieldMachine5.Value;
            str = str2num(strrep(value,'.',' '));
            global params;
            params.slantIPAddress_na.Value(1) = str(1);
            params.slantIPAddress_na.Value(2) = str(2);
            params.slantIPAddress_na.Value(3) = str(3);
            params.slantIPAddress_na.Value(4) = str(4);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ControlTab = uitab(app.TabGroup);
            app.ControlTab.Title = 'Control';
            app.OperationModeButtonGroup = uibuttongroup(app.ControlTab);
            app.OperationModeButtonGroup.Title = 'Operation Mode';
            app.ManualButton = uiradiobutton(app.OperationModeButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ManualButton.Value = true;
            app.AutomaticButton = uiradiobutton(app.OperationModeButtonGroup);
            app.AutomaticButton.Text = 'Automatic';
            app.PDControllerGainsPanel = uipanel(app.ControlTab);
            app.PDControllerGainsPanel.Title = 'PD Controller Gains';
            app.RollControlLabel = uilabel(app.PDControllerGainsPanel);
            app.RollControlLabel.Text = 'Roll Control';
            app.PitchControlLabel = uilabel(app.PDControllerGainsPanel);
            app.PitchControlLabel.Text = 'Pitch Control';
            app.ProportionalGainEditFieldLabel = uilabel(app.PDControllerGainsPanel);
            app.ProportionalGainEditFieldLabel.Text = 'Proportional Gain';
            app.ProportionalGainEditFieldRoll = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.ProportionalGainEditFieldRoll.ValueChangedFcn = createCallbackFcn(app, @ProportionalGainEditFieldRollValueChanged, true);
            app.TimeConstantEditFieldLabel = uilabel(app.PDControllerGainsPanel);
            app.TimeConstantEditFieldLabel.Text = 'Time Constant';
            app.TimeConstantEditFieldRoll = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.TimeConstantEditFieldRoll.ValueChangedFcn = createCallbackFcn(app, @TimeConstantEditFieldRollValueChanged, true);
            app.DerivativeGainEditFieldLabel = uilabel(app.PDControllerGainsPanel);
            app.DerivativeGainEditFieldLabel.Text = 'Derivative Gain';
            app.DerivativeGainEditFieldRoll = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.DerivativeGainEditFieldRoll.ValueChangedFcn = createCallbackFcn(app, @DerivativeGainEditFieldRollValueChanged, true);
            app.AltitudeControlLabel = uilabel(app.PDControllerGainsPanel);
            app.AltitudeControlLabel.Text = 'Altitude Control';
            app.TimeConstantEditField_2Label = uilabel(app.PDControllerGainsPanel);
            app.TimeConstantEditField_2Label.Text = 'Time Constant';
            app.TimeConstantEditFieldPitch = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.TimeConstantEditFieldPitch.ValueChangedFcn = createCallbackFcn(app, @TimeConstantEditFieldPitchValueChanged, true);
            app.DerivativeGainEditField_2Label = uilabel(app.PDControllerGainsPanel);
            app.DerivativeGainEditField_2Label.Text = 'Derivative Gain';
            app.DerivativeGainEditFieldPitch = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.DerivativeGainEditFieldPitch.ValueChangedFcn = createCallbackFcn(app, @DerivativeGainEditFieldPitchValueChanged, true);
            app.ProportionalGainEditField_2Label = uilabel(app.PDControllerGainsPanel);
            app.ProportionalGainEditField_2Label.Text = 'Proportional Gain';
            app.ProportionalGainEditFieldPitch = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.ProportionalGainEditFieldPitch.ValueChangedFcn = createCallbackFcn(app, @ProportionalGainEditFieldPitchValueChanged, true);
            app.TimeConstantEditField_3Label = uilabel(app.PDControllerGainsPanel);
            app.TimeConstantEditField_3Label.Text = 'Time Constant';
            app.TimeConstantEditFieldAltitude = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.TimeConstantEditFieldAltitude.ValueChangedFcn = createCallbackFcn(app, @TimeConstantEditFieldAltitudeValueChanged, true);
            app.DerivativeGainEditField_3Label = uilabel(app.PDControllerGainsPanel);
            app.DerivativeGainEditField_3Label.Text = 'Derivative Gain';
            app.DerivativeGainEditFieldAltitude = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.DerivativeGainEditFieldAltitude.ValueChangedFcn = createCallbackFcn(app, @DerivativeGainEditFieldAltitudeValueChanged, true);
            app.ProportionalGainEditField_3Label = uilabel(app.PDControllerGainsPanel);
            app.ProportionalGainEditField_3Label.Text = 'Proportional Gain';
            app.ProportionalGainEditFieldAltitude = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.ProportionalGainEditFieldAltitude.ValueChangedFcn = createCallbackFcn(app, @ProportionalGainEditFieldAltitudeValueChanged, true);
            app.MotorControlPanel = uipanel(app.ControlTab);
            app.MotorControlPanel.Title = 'Motor Control';
            app.AllSliderLabel = uilabel(app.MotorControlPanel);
            app.AllSliderLabel.Text = 'All';
            app.AllSlider = uislider(app.MotorControlPanel);
            app.AllSlider.Limits = [-1 1];
            app.AllSlider.ValueChangedFcn = createCallbackFcn(app, @AllSliderValueChanged, true);
            app.Motor3SliderLabel = uilabel(app.MotorControlPanel);
            app.Motor3SliderLabel.Text = 'Motor 3';
            app.Motor3Slider = uislider(app.MotorControlPanel);
            app.Motor3Slider.Limits = [-1 1];
            app.Motor3Slider.ValueChangedFcn = createCallbackFcn(app, @Motor3SliderValueChanged, true);
            app.Motor1SliderLabel = uilabel(app.MotorControlPanel);
            app.Motor1SliderLabel.Text = 'Motor 1';
            app.Motor1Slider = uislider(app.MotorControlPanel);
            app.Motor1Slider.Limits = [-1 1];
            app.Motor1Slider.ValueChangedFcn = createCallbackFcn(app, @Motor1SliderValueChanged, true);
            app.Motor2SliderLabel = uilabel(app.MotorControlPanel);
            app.Motor2SliderLabel.Text = 'Motor 2';
            app.Motor2Slider = uislider(app.MotorControlPanel);
            app.Motor2Slider.Limits = [-1 1];
            app.Motor2Slider.ValueChangedFcn = createCallbackFcn(app, @Motor2SliderValueChanged, true);
            app.MotorConfigurationPanel = uipanel(app.ControlTab);
            app.MotorConfigurationPanel.Title = 'Motor Configuration';
            app.MaxMotorVoltageEditFieldLabel = uilabel(app.MotorConfigurationPanel);
            app.MaxMotorVoltageEditFieldLabel.Text = 'Max Motor Voltage';
            app.MaxMotorVoltageEditField = uieditfield(app.MotorConfigurationPanel, 'numeric');
            app.MaxMotorVoltageEditField.ValueChangedFcn = createCallbackFcn(app, @MaxMotorVoltageEditFieldValueChanged, true);
            app.InitialCenterofMassPositionPanel = uipanel(app.ControlTab);
            app.InitialCenterofMassPositionPanel.Title = 'Initial Center of Mass Position';
            app.CoMPositionEditFieldLabel = uilabel(app.InitialCenterofMassPositionPanel);
            app.CoMPositionEditFieldLabel.Text = 'CoM Position';
            app.CoMPositionEditFieldX = uieditfield(app.InitialCenterofMassPositionPanel, 'numeric');
            app.CoMPositionEditFieldX.ValueChangedFcn = createCallbackFcn(app, @CoMPositionEditFieldXValueChanged, true);
            app.CoMPositionEditFieldY = uieditfield(app.InitialCenterofMassPositionPanel, 'numeric');
            app.CoMPositionEditFieldY.ValueChangedFcn = createCallbackFcn(app, @CoMPositionEditFieldYValueChanged, true);
            app.CoMPositionEditFieldZ = uieditfield(app.InitialCenterofMassPositionPanel, 'numeric');
            app.CoMPositionEditFieldZ.ValueChangedFcn = createCallbackFcn(app, @CoMPositionEditFieldZValueChanged, true);
            app.CameraControlTab = uitab(app.TabGroup);
            app.CameraControlTab.Title = 'Camera Control';
            app.CameraSelectionButtonGroup = uibuttongroup(app.CameraControlTab);
            app.CameraSelectionButtonGroup.Title = 'Camera Selection';
            app.BottomCameraAButton = uiradiobutton(app.CameraSelectionButtonGroup);
            app.BottomCameraAButton.Text = 'Bottom Camera A';
            app.BottomCameraAButton.Value = true;
            app.BottomCameraBButton = uiradiobutton(app.CameraSelectionButtonGroup);
            app.BottomCameraBButton.Text = 'Bottom Camera B';
            app.SideCameraPanel = uipanel(app.CameraControlTab);
            app.SideCameraPanel.Title = 'Side Camera';
            app.ROIHoldPositionpxEditFieldLabel = uilabel(app.SideCameraPanel);
            app.ROIHoldPositionpxEditFieldLabel.Text = 'ROI Hold Position (px)';
            app.ROIHoldPositionpxEditFieldSideRow = uieditfield(app.SideCameraPanel, 'numeric');
            app.ROIHoldPositionpxEditFieldSideRow.ValueChangedFcn = createCallbackFcn(app, @ROIHoldPositionpxEditFieldSideRowValueChanged, true);
            app.ROIHoldPositionpxEditFieldSideCol = uieditfield(app.SideCameraPanel, 'numeric');
            app.ROIHoldPositionpxEditFieldSideCol.ValueChangedFcn = createCallbackFcn(app, @ROIHoldPositionpxEditFieldSideColValueChanged, true);
            app.HoldReleaseButtonSide = uibutton(app.SideCameraPanel, 'push');
            app.HoldReleaseButtonSide.Text = 'Hold/Release';
            app.ROISizeEditFieldLabel = uilabel(app.SideCameraPanel);
            app.ROISizeEditFieldLabel.Text = 'ROI Size';
            app.ROISizeEditFieldSideRow = uieditfield(app.SideCameraPanel, 'numeric');
            app.ROISizeEditFieldSideRow.ValueChangedFcn = createCallbackFcn(app, @ROISizeEditFieldSideRowValueChanged, true);
            app.ROISizeEditFieldSideCol = uieditfield(app.SideCameraPanel, 'numeric');
            app.ROISizeEditFieldSideCol.ValueChangedFcn = createCallbackFcn(app, @ROISizeEditFieldSideColValueChanged, true);
            app.ImageDimensionsEditFieldLabel = uilabel(app.SideCameraPanel);
            app.ImageDimensionsEditFieldLabel.Text = 'Image Dimensions';
            app.ImageDimensionsEditFieldSideRow = uieditfield(app.SideCameraPanel, 'numeric');
            app.ImageDimensionsEditFieldSideRow.ValueChangedFcn = createCallbackFcn(app, @ImageDimensionsEditFieldSideRowValueChanged, true);
            app.ImageDimensionsEditFieldSideCol = uieditfield(app.SideCameraPanel, 'numeric');
            app.ImageDimensionsEditFieldSideCol.ValueChangedFcn = createCallbackFcn(app, @ImageDimensionsEditFieldSideColValueChanged, true);
            app.CrosshairWidthEditFieldLabel = uilabel(app.SideCameraPanel);
            app.CrosshairWidthEditFieldLabel.Text = 'Crosshair Width';
            app.CrosshairWidthEditFieldSide = uieditfield(app.SideCameraPanel, 'numeric');
            app.CrosshairWidthEditFieldSide.ValueChangedFcn = createCallbackFcn(app, @CrosshairWidthEditFieldSideValueChanged, true);
            app.VideoDecimationEditFieldLabel = uilabel(app.SideCameraPanel);
            app.VideoDecimationEditFieldLabel.Text = 'Video Decimation';
            app.VideoDecimationEditFieldSide = uieditfield(app.SideCameraPanel, 'numeric');
            app.VideoDecimationEditFieldSide.ValueChangedFcn = createCallbackFcn(app, @VideoDecimationEditFieldSideValueChanged, true);
            app.ImageWidthEditFieldLabel = uilabel(app.SideCameraPanel);
            app.ImageWidthEditFieldLabel.Text = 'Image Width';
            app.ImageWidthEditFieldSide = uieditfield(app.SideCameraPanel, 'numeric');
            app.ImageWidthEditFieldSide.ValueChangedFcn = createCallbackFcn(app, @ImageWidthEditFieldSideValueChanged, true);
            app.BottomCameraPanel = uipanel(app.CameraControlTab);
            app.BottomCameraPanel.Title = 'Bottom Camera';
            app.ROIHoldPositionpxEditFieldLabel_2 = uilabel(app.BottomCameraPanel);
            app.ROIHoldPositionpxEditFieldLabel_2.Text = 'ROI Hold Position (px)';
            app.ROIHoldPositionpxEditFieldBottomARow = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ROIHoldPositionpxEditFieldBottomARow.ValueChangedFcn = createCallbackFcn(app, @ROIHoldPositionpxEditFieldBottomARowValueChanged, true);
            app.ROIHoldPositionpxEditFieldBottomACol = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ROIHoldPositionpxEditFieldBottomACol.ValueChangedFcn = createCallbackFcn(app, @ROIHoldPositionpxEditFieldBottomAColValueChanged, true);
            app.HoldReleaseButtonBottomA = uibutton(app.BottomCameraPanel, 'push');
            app.HoldReleaseButtonBottomA.Text = 'Hold/Release';
            app.ROISizeEditField_5Label = uilabel(app.BottomCameraPanel);
            app.ROISizeEditField_5Label.Text = 'ROI Size';
            app.ROISizeEditFieldBottomARow = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ROISizeEditFieldBottomARow.ValueChangedFcn = createCallbackFcn(app, @ROISizeEditFieldBottomARowValueChanged, true);
            app.ROISizeEditFieldBottomACol = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ROISizeEditFieldBottomACol.ValueChangedFcn = createCallbackFcn(app, @ROISizeEditFieldBottomAColValueChanged, true);
            app.ImageDimensionsEditField_2Label = uilabel(app.BottomCameraPanel);
            app.ImageDimensionsEditField_2Label.Text = 'Image Dimensions';
            app.ImageDimensionsEditFieldBottomRow = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ImageDimensionsEditFieldBottomRow.ValueChangedFcn = createCallbackFcn(app, @ImageDimensionsEditFieldBottomRowValueChanged, true);
            app.ImageDimensionsEditFieldBottomCol = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ImageDimensionsEditFieldBottomCol.ValueChangedFcn = createCallbackFcn(app, @ImageDimensionsEditFieldBottomColValueChanged, true);
            app.CrosshairWidthEditField_2Label = uilabel(app.BottomCameraPanel);
            app.CrosshairWidthEditField_2Label.Text = 'Crosshair Width';
            app.CrosshairWidthEditFieldBottomA = uieditfield(app.BottomCameraPanel, 'numeric');
            app.CrosshairWidthEditFieldBottomA.ValueChangedFcn = createCallbackFcn(app, @CrosshairWidthEditFieldBottomAValueChanged, true);
            app.VideoDecimationEditField_2Label = uilabel(app.BottomCameraPanel);
            app.VideoDecimationEditField_2Label.Text = 'Video Decimation';
            app.VideoDecimationEditFieldBottom = uieditfield(app.BottomCameraPanel, 'numeric');
            app.VideoDecimationEditFieldBottom.ValueChangedFcn = createCallbackFcn(app, @VideoDecimationEditFieldBottomValueChanged, true);
            app.ImageWidthEditField_2Label = uilabel(app.BottomCameraPanel);
            app.ImageWidthEditField_2Label.Text = 'Image Width';
            app.ImageWidthEditFieldBottom = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ImageWidthEditFieldBottom.ValueChangedFcn = createCallbackFcn(app, @ImageWidthEditFieldBottomValueChanged, true);
            app.BottomCameraALabel = uilabel(app.BottomCameraPanel);
            app.BottomCameraALabel.Text = 'Bottom Camera A';
            app.BottomCameraBLabel = uilabel(app.BottomCameraPanel);
            app.BottomCameraBLabel.Text = 'Bottom Camera B';
            app.ROIHoldPositionpxEditFieldBottomBCol = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ROIHoldPositionpxEditFieldBottomBCol.ValueChangedFcn = createCallbackFcn(app, @ROIHoldPositionpxEditFieldBottomBColValueChanged, true);
            app.HoldReleaseButtonBottomB = uibutton(app.BottomCameraPanel, 'push');
            app.HoldReleaseButtonBottomB.Text = 'Hold/Release';
            app.ROISizeEditFieldBottomBCol = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ROISizeEditFieldBottomBCol.ValueChangedFcn = createCallbackFcn(app, @ROISizeEditFieldBottomBColValueChanged, true);
            app.ROIHoldPositionpxEditFieldLabel_4 = uilabel(app.BottomCameraPanel);
            app.ROIHoldPositionpxEditFieldLabel_4.Text = 'ROI Hold Position (px)';
            app.ROIHoldPositionpxEditFieldBottomBRow = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ROIHoldPositionpxEditFieldBottomBRow.ValueChangedFcn = createCallbackFcn(app, @ROIHoldPositionpxEditFieldBottomBRowValueChanged, true);
            app.ROISizeEditField_12Label = uilabel(app.BottomCameraPanel);
            app.ROISizeEditField_12Label.Text = 'ROI Size';
            app.ROISizeEditFieldBottomBRow = uieditfield(app.BottomCameraPanel, 'numeric');
            app.ROISizeEditFieldBottomBRow.ValueChangedFcn = createCallbackFcn(app, @ROISizeEditFieldBottomBRowValueChanged, true);
            app.CrosshairWidthEditField_4Label = uilabel(app.BottomCameraPanel);
            app.CrosshairWidthEditField_4Label.Text = 'Crosshair Width';
            app.CrosshairWidthEditFieldBottomB = uieditfield(app.BottomCameraPanel, 'numeric');
            app.CrosshairWidthEditFieldBottomB.ValueChangedFcn = createCallbackFcn(app, @CrosshairWidthEditFieldBottomBValueChanged, true);
            app.BothCamerasLabel = uilabel(app.BottomCameraPanel);
            app.BothCamerasLabel.Text = 'Both Cameras';
            app.SlantCameraPanel = uipanel(app.CameraControlTab);
            app.SlantCameraPanel.Title = 'Slant Camera';
            app.ROIHoldPositionpxEditFieldLabel_3 = uilabel(app.SlantCameraPanel);
            app.ROIHoldPositionpxEditFieldLabel_3.Text = 'ROI Hold Position (px)';
            app.ROIHoldPositionpxEditFieldSlantRow = uieditfield(app.SlantCameraPanel, 'numeric');
            app.ROIHoldPositionpxEditFieldSlantRow.ValueChangedFcn = createCallbackFcn(app, @ROIHoldPositionpxEditFieldSlantRowValueChanged, true);
            app.ROIHoldPositionpxEditFieldSlantCol = uieditfield(app.SlantCameraPanel, 'numeric');
            app.ROIHoldPositionpxEditFieldSlantCol.ValueChangedFcn = createCallbackFcn(app, @ROIHoldPositionpxEditFieldSlantColValueChanged, true);
            app.HoldReleaseButtonSideSlant = uibutton(app.SlantCameraPanel, 'push');
            app.HoldReleaseButtonSideSlant.Text = 'Hold/Release';
            app.ROISizeEditField_8Label = uilabel(app.SlantCameraPanel);
            app.ROISizeEditField_8Label.Text = 'ROI Size';
            app.ROISizeEditFieldSlantRow = uieditfield(app.SlantCameraPanel, 'numeric');
            app.ROISizeEditFieldSlantRow.ValueChangedFcn = createCallbackFcn(app, @ROISizeEditFieldSlantRowValueChanged, true);
            app.ROISizeEditFieldSlantCol = uieditfield(app.SlantCameraPanel, 'numeric');
            app.ROISizeEditFieldSlantCol.ValueChangedFcn = createCallbackFcn(app, @ROISizeEditFieldSlantColValueChanged, true);
            app.ImageDimensionsEditField_3Label = uilabel(app.SlantCameraPanel);
            app.ImageDimensionsEditField_3Label.Text = 'Image Dimensions';
            app.ImageDimensionsEditFieldSlantRow = uieditfield(app.SlantCameraPanel, 'numeric');
            app.ImageDimensionsEditFieldSlantRow.ValueChangedFcn = createCallbackFcn(app, @ImageDimensionsEditFieldSlantRowValueChanged, true);
            app.ImageDimensionsEditFieldSlantCol = uieditfield(app.SlantCameraPanel, 'numeric');
            app.ImageDimensionsEditFieldSlantCol.ValueChangedFcn = createCallbackFcn(app, @ImageDimensionsEditFieldSlantColValueChanged, true);
            app.CrosshairWidthEditField_3Label = uilabel(app.SlantCameraPanel);
            app.CrosshairWidthEditField_3Label.Text = 'Crosshair Width';
            app.CrosshairWidthEditFieldSlant = uieditfield(app.SlantCameraPanel, 'numeric');
            app.CrosshairWidthEditFieldSlant.ValueChangedFcn = createCallbackFcn(app, @CrosshairWidthEditFieldSlantValueChanged, true);
            app.VideoDecimationEditField_3Label = uilabel(app.SlantCameraPanel);
            app.VideoDecimationEditField_3Label.Text = 'Video Decimation';
            app.VideoDecimationEditFieldSlant = uieditfield(app.SlantCameraPanel, 'numeric');
            app.VideoDecimationEditFieldSlant.ValueChangedFcn = createCallbackFcn(app, @VideoDecimationEditFieldSlantValueChanged, true);
            app.ImageWidthEditField_3Label = uilabel(app.SlantCameraPanel);
            app.ImageWidthEditField_3Label.Text = 'Image Width';
            app.ImageWidthEditFieldSlant = uieditfield(app.SlantCameraPanel, 'numeric');
            app.ImageWidthEditFieldSlant.ValueChangedFcn = createCallbackFcn(app, @ImageWidthEditFieldSlantValueChanged, true);
            app.CameraSettingsTab = uitab(app.TabGroup);
            app.CameraSettingsTab.Title = 'Camera Settings';
            app.DotPropertiesPanel = uipanel(app.CameraSettingsTab);
            app.DotPropertiesPanel.Title = 'Dot Properties';
            app.SideDotSeparationDistanceEditField_2Label = uilabel(app.DotPropertiesPanel);
            app.SideDotSeparationDistanceEditField_2Label.Text = 'Side Dot Separation Distance';
            app.SideDotSeparationDistanceEditField = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.SideDotSeparationDistanceEditField.ValueChangedFcn = createCallbackFcn(app, @SideDotSeparationDistanceEditFieldValueChanged, true);
            app.SideDotPositionVectorEditFieldLabel_2 = uilabel(app.DotPropertiesPanel);
            app.SideDotPositionVectorEditFieldLabel_2.Text = 'Side Dot Position Vector';
            app.SideDotPositionVectorEditFieldX = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.SideDotPositionVectorEditFieldX.ValueChangedFcn = createCallbackFcn(app, @SideDotPositionVectorEditFieldXValueChanged, true);
            app.BottomADotSeparationDistanceEditField_2Label = uilabel(app.DotPropertiesPanel);
            app.BottomADotSeparationDistanceEditField_2Label.Text = 'Bottom A Dot Separation Distance';
            app.BottomADotSeparationDistanceEditField = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomADotSeparationDistanceEditField.ValueChangedFcn = createCallbackFcn(app, @BottomADotSeparationDistanceEditFieldValueChanged, true);
            app.BottomADotPositionVectorEditFieldLabel_2 = uilabel(app.DotPropertiesPanel);
            app.BottomADotPositionVectorEditFieldLabel_2.Text = 'Bottom A Dot Position Vector';
            app.BottomADotPositionVectorEditFieldX = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomADotPositionVectorEditFieldX.ValueChangedFcn = createCallbackFcn(app, @BottomADotPositionVectorEditFieldXValueChanged, true);
            app.BottomBDotSeparationDistanceEditField_2Label = uilabel(app.DotPropertiesPanel);
            app.BottomBDotSeparationDistanceEditField_2Label.Text = 'Bottom B Dot Separation Distance';
            app.BottomBDotSeparationDistanceEditField = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomBDotSeparationDistanceEditField.ValueChangedFcn = createCallbackFcn(app, @BottomBDotSeparationDistanceEditFieldValueChanged, true);
            app.BottomBDotPositionValuieEditFieldLabel_2 = uilabel(app.DotPropertiesPanel);
            app.BottomBDotPositionValuieEditFieldLabel_2.Text = 'Bottom B Dot Position Valuie';
            app.BottomBDotPositionValuieEditFieldX = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomBDotPositionValuieEditFieldX.ValueChangedFcn = createCallbackFcn(app, @BottomBDotPositionValuieEditFieldXValueChanged, true);
            app.SideDotPositionVectorEditFieldY = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.SideDotPositionVectorEditFieldY.ValueChangedFcn = createCallbackFcn(app, @SideDotPositionVectorEditFieldYValueChanged, true);
            app.SideDotPositionVectorEditFieldZ = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.SideDotPositionVectorEditFieldZ.ValueChangedFcn = createCallbackFcn(app, @SideDotPositionVectorEditFieldZValueChanged, true);
            app.BottomADotPositionVectorEditFieldY = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomADotPositionVectorEditFieldY.ValueChangedFcn = createCallbackFcn(app, @BottomADotPositionVectorEditFieldYValueChanged, true);
            app.BottomADotPositionVectorEditFieldZ = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomADotPositionVectorEditFieldZ.ValueChangedFcn = createCallbackFcn(app, @BottomADotPositionVectorEditFieldZValueChanged, true);
            app.BottomBDotPositionValuieEditFieldY = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomBDotPositionValuieEditFieldY.ValueChangedFcn = createCallbackFcn(app, @BottomBDotPositionValuieEditFieldYValueChanged, true);
            app.BottomBDotPositionValuieEditFieldZ = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomBDotPositionValuieEditFieldZ.ValueChangedFcn = createCallbackFcn(app, @BottomBDotPositionValuieEditFieldZValueChanged, true);
            app.CameraSpecificationsPanel = uipanel(app.CameraSettingsTab);
            app.CameraSpecificationsPanel.Title = 'Camera Specifications';
            app.CameraFOVEditFieldLabel_2 = uilabel(app.CameraSpecificationsPanel);
            app.CameraFOVEditFieldLabel_2.Text = 'Camera FOV';
            app.CameraFOVEditFieldHor = uieditfield(app.CameraSpecificationsPanel, 'numeric');
            app.CameraFOVEditFieldHor.ValueChangedFcn = createCallbackFcn(app, @CameraFOVEditFieldHorValueChanged, true);
            app.CameraFOVEditFieldVer = uieditfield(app.CameraSpecificationsPanel, 'numeric');
            app.CameraFOVEditFieldVer.ValueChangedFcn = createCallbackFcn(app, @CameraFOVEditFieldVerValueChanged, true);
            app.ImageErrorSettingsPanel = uipanel(app.CameraSettingsTab);
            app.ImageErrorSettingsPanel.Title = 'Image Error Settings';
            app.ThresholdEditField_2Label = uilabel(app.ImageErrorSettingsPanel);
            app.ThresholdEditField_2Label.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.ImageErrorSettingsPanel, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.DebounceTimeEditField_2Label = uilabel(app.ImageErrorSettingsPanel);
            app.DebounceTimeEditField_2Label.Text = 'Debounce Time';
            app.DebounceTimeEditField = uieditfield(app.ImageErrorSettingsPanel, 'numeric');
            app.DebounceTimeEditField.ValueChangedFcn = createCallbackFcn(app, @DebounceTimeEditFieldValueChanged, true);
            app.CameraOrientationPanel = uipanel(app.CameraSettingsTab);
            app.CameraOrientationPanel.Title = 'Camera Orientation';
            app.SideCameraLabel2 = uilabel(app.CameraOrientationPanel);
            app.SideCameraLabel2.Text = 'Side Camera';
            app.EulerAngleEditFieldLabel_2 = uilabel(app.CameraOrientationPanel);
            app.EulerAngleEditFieldLabel_2.Text = 'Euler Angle';
            app.EulerAngleEditFieldSideRoll = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldSideRoll.ValueChangedFcn = createCallbackFcn(app, @EulerAngleEditFieldSideRollValueChanged, true);
            app.BottomCameraLabel = uilabel(app.CameraOrientationPanel);
            app.BottomCameraLabel.Text = 'Bottom Camera';
            app.EulerAngleEditField_2Label_2 = uilabel(app.CameraOrientationPanel);
            app.EulerAngleEditField_2Label_2.Text = 'Euler Angle';
            app.EulerAngleEditFieldBottomRoll = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldBottomRoll.ValueChangedFcn = createCallbackFcn(app, @EulerAngleEditFieldBottomRollValueChanged, true);
            app.EulerAngleEditFieldBottomPitch = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldBottomPitch.ValueChangedFcn = createCallbackFcn(app, @EulerAngleEditFieldBottomPitchValueChanged, true);
            app.EulerAngleEditFieldBottomYaw = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldBottomYaw.ValueChangedFcn = createCallbackFcn(app, @EulerAngleEditFieldBottomYawValueChanged, true);
            app.PositionEditField_8Label_2 = uilabel(app.CameraOrientationPanel);
            app.PositionEditField_8Label_2.Text = 'Position';
            app.PositionEditFieldBottomX = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldBottomX.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldBottomXValueChanged, true);
            app.PositionEditFieldBottomY = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldBottomY.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldBottomYValueChanged, true);
            app.PositionEditFieldBottomZ = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldBottomZ.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldBottomZValueChanged, true);
            app.SlantCameraLabel2 = uilabel(app.CameraOrientationPanel);
            app.SlantCameraLabel2.Text = 'Slant Camera';
            app.EulerAngleEditField_3Label_2 = uilabel(app.CameraOrientationPanel);
            app.EulerAngleEditField_3Label_2.Text = 'Euler Angle';
            app.EulerAngleEditFieldSlantRoll = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldSlantRoll.ValueChangedFcn = createCallbackFcn(app, @EulerAngleEditFieldSlantRollValueChanged, true);
            app.PositionEditField_11Label_2 = uilabel(app.CameraOrientationPanel);
            app.PositionEditField_11Label_2.Text = 'Position';
            app.PositionEditFieldSlantX = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldSlantX.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldSlantXValueChanged, true);
            app.PositionEditFieldSlantY = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldSlantY.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldSlantYValueChanged, true);
            app.EulerAngleEditFieldSlantPitch = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldSlantPitch.ValueChangedFcn = createCallbackFcn(app, @EulerAngleEditFieldSlantPitchValueChanged, true);
            app.EulerAngleEditFieldSlantYaw = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldSlantYaw.ValueChangedFcn = createCallbackFcn(app, @EulerAngleEditFieldSlantYawValueChanged, true);
            app.PositionEditFieldSlantZ = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldSlantZ.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldSlantZValueChanged, true);
            app.PositionEditField_16Label_2 = uilabel(app.CameraOrientationPanel);
            app.PositionEditField_16Label_2.Text = 'Position';
            app.PositionEditFieldSideX = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldSideX.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldSideXValueChanged, true);
            app.EulerAngleEditFieldSidePitch = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldSidePitch.ValueChangedFcn = createCallbackFcn(app, @EulerAngleEditFieldSidePitchValueChanged, true);
            app.EulerAngleEditFieldSideYaw = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldSideYaw.ValueChangedFcn = createCallbackFcn(app, @EulerAngleEditFieldSideYawValueChanged, true);
            app.PositionEditFieldSideY = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldSideY.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldSideYValueChanged, true);
            app.PositionEditFieldSideZ = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldSideZ.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldSideZValueChanged, true);
            app.CommunicationTab = uitab(app.TabGroup);
            app.CommunicationTab.Title = 'Communication';
            app.UDPPTPConfigurationPanel = uipanel(app.CommunicationTab);
            app.UDPPTPConfigurationPanel.Title = 'UDP/PTP Configuration';
            app.PTPOffsetEditFieldLabel = uilabel(app.UDPPTPConfigurationPanel);
            app.PTPOffsetEditFieldLabel.Text = 'PTP Offset';
            app.PTPOffsetEditField = uieditfield(app.UDPPTPConfigurationPanel, 'numeric');
            app.PTPOffsetEditField.ValueChangedFcn = createCallbackFcn(app, @PTPOffsetEditFieldValueChanged, true);
            app.SyncIntervalEditFieldLabel = uilabel(app.UDPPTPConfigurationPanel);
            app.SyncIntervalEditFieldLabel.Text = 'Sync Interval';
            app.SyncIntervalEditField = uieditfield(app.UDPPTPConfigurationPanel, 'numeric');
            app.SyncIntervalEditField.ValueChangedFcn = createCallbackFcn(app, @SyncIntervalEditFieldValueChanged, true);
            app.Machine3Panel = uipanel(app.CommunicationTab);
            app.Machine3Panel.Title = 'Machine 3';
            app.PortNumberEditFieldLabel = uilabel(app.Machine3Panel);
            app.PortNumberEditFieldLabel.Text = 'Port Number';
            app.PortNumberEditFieldMachine3 = uieditfield(app.Machine3Panel, 'numeric');
            app.PortNumberEditFieldMachine3.ValueChangedFcn = createCallbackFcn(app, @PortNumberEditFieldMachine3ValueChanged, true);
            app.MinDelayEditFieldLabel = uilabel(app.Machine3Panel);
            app.MinDelayEditFieldLabel.Text = 'Min Delay';
            app.MinDelayEditFieldMachine3 = uieditfield(app.Machine3Panel, 'numeric');
            app.MinDelayEditFieldMachine3.ValueChangedFcn = createCallbackFcn(app, @MinDelayEditFieldMachine3ValueChanged, true);
            app.IPAddressEditFieldLabel = uilabel(app.Machine3Panel);
            app.IPAddressEditFieldLabel.Text = 'IP Address';
            app.IPAddressEditFieldMachine3 = uieditfield(app.Machine3Panel, 'text');
            app.IPAddressEditFieldMachine3.ValueChangedFcn = createCallbackFcn(app, @IPAddressEditFieldMachine3ValueChanged, true);
            app.Machine4Panel = uipanel(app.CommunicationTab);
            app.Machine4Panel.Title = 'Machine 4';
            app.PortNumberEditField_2Label = uilabel(app.Machine4Panel);
            app.PortNumberEditField_2Label.Text = 'Port Number';
            app.PortNumberEditFieldMachine4 = uieditfield(app.Machine4Panel, 'numeric');
            app.PortNumberEditFieldMachine4.ValueChangedFcn = createCallbackFcn(app, @PortNumberEditFieldMachine4ValueChanged, true);
            app.MinDelayEditField_2Label = uilabel(app.Machine4Panel);
            app.MinDelayEditField_2Label.Text = 'Min Delay';
            app.MinDelayEditFieldMachine4 = uieditfield(app.Machine4Panel, 'numeric');
            app.MinDelayEditFieldMachine4.ValueChangedFcn = createCallbackFcn(app, @MinDelayEditFieldMachine4ValueChanged, true);
            app.IPAddressEditField_2Label = uilabel(app.Machine4Panel);
            app.IPAddressEditField_2Label.Text = 'IP Address';
            app.IPAddressEditFieldMachine4 = uieditfield(app.Machine4Panel, 'text');
            app.IPAddressEditFieldMachine4.ValueChangedFcn = createCallbackFcn(app, @IPAddressEditFieldMachine4ValueChanged, true);
            app.Machine5Panel = uipanel(app.CommunicationTab);
            app.Machine5Panel.Title = 'Machine 5';
            app.PortNumberEditField_3Label = uilabel(app.Machine5Panel);
            app.PortNumberEditField_3Label.Text = 'Port Number';
            app.PortNumberEditFieldMachine5 = uieditfield(app.Machine5Panel, 'numeric');
            app.PortNumberEditFieldMachine5.ValueChangedFcn = createCallbackFcn(app, @PortNumberEditFieldMachine5ValueChanged, true);
            app.MinDelayEditField_3Label = uilabel(app.Machine5Panel);
            app.MinDelayEditField_3Label.Text = 'Min Delay';
            app.MinDelayEditFieldMachine5 = uieditfield(app.Machine5Panel, 'numeric');
            app.MinDelayEditFieldMachine5.ValueChangedFcn = createCallbackFcn(app, @MinDelayEditFieldMachine5ValueChanged, true);
            app.IPAddressEditField_3Label = uilabel(app.Machine5Panel);
            app.IPAddressEditField_3Label.Text = 'IP Address';
            app.IPAddressEditFieldMachine5 = uieditfield(app.Machine5Panel, 'text');
            app.IPAddressEditFieldMachine5.ValueChangedFcn = createCallbackFcn(app, @IPAddressEditFieldMachine5ValueChanged, true);
            app.Machine2Panel = uipanel(app.CommunicationTab);
            app.Machine2Panel.Title = 'Machine 2';
            app.AnnounceIntervalEditFieldLabel = uilabel(app.Machine2Panel);
            app.AnnounceIntervalEditFieldLabel.Text = 'Announce Interval';
            app.AnnounceIntervalEditField = uieditfield(app.Machine2Panel, 'numeric');
            app.AnnounceIntervalEditField.ValueChangedFcn = createCallbackFcn(app, @AnnounceIntervalEditFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end